classdef HEXDesignerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DesignParametersTab             matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        ProjectParametersTab            matlab.ui.container.Tab
        GridLayout_2                    matlab.ui.container.GridLayout
        ColdLabel                       matlab.ui.control.Label
        HotLabel                        matlab.ui.control.Label
        FlowRateHotkgsEditFieldLabel    matlab.ui.control.Label
        FlowRateHotkgsEditField         matlab.ui.control.NumericEditField
        InletTemperatureHotKEditFieldLabel  matlab.ui.control.Label
        InletTemperatureHotKEditField   matlab.ui.control.NumericEditField
        OutletTemperatureHotKEditFieldLabel  matlab.ui.control.Label
        OutletTemperatureHotKEditField  matlab.ui.control.NumericEditField
        PumpEfficiencyHotEditFieldLabel  matlab.ui.control.Label
        PumpEfficiencyHotEditField      matlab.ui.control.NumericEditField
        FlowRateColdkgsEditFieldLabel   matlab.ui.control.Label
        FlowRateColdkgsEditField        matlab.ui.control.NumericEditField
        InletTemperatureColdKEditFieldLabel  matlab.ui.control.Label
        InletTemperatureColdKEditField  matlab.ui.control.NumericEditField
        OutletTemperatureColdKEditFieldLabel  matlab.ui.control.Label
        OutletTemperatureColdKEditField  matlab.ui.control.NumericEditField
        PumpEfficiencyColdEditFieldLabel  matlab.ui.control.Label
        PumpEfficiencyColdEditField     matlab.ui.control.NumericEditField
        LifeTimeyearEditFieldLabel      matlab.ui.control.Label
        LifeTimeyearEditField           matlab.ui.control.NumericEditField
        PlateMaterialLabel              matlab.ui.control.Label
        GasketMaterialLabel             matlab.ui.control.Label
        StainlessSteelAISI316Label      matlab.ui.control.Label
        PlateMaterialLabel_3            matlab.ui.control.Label
        FoulingResistanceColdm2KWEditFieldLabel  matlab.ui.control.Label
        FoulingResistanceColdm2KWEditField  matlab.ui.control.NumericEditField
        FoulingResistanceHotm2KWEditFieldLabel  matlab.ui.control.Label
        FoulingResistanceHotm2KWEditField  matlab.ui.control.NumericEditField
        HEXPropertiesTab                matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        MinimumValueLabel               matlab.ui.control.Label
        MaximumValueLabel               matlab.ui.control.Label
        IterationSizeLabel              matlab.ui.control.Label
        ExactLabel                      matlab.ui.control.Label
        ParameterLabel                  matlab.ui.control.Label
        PlateThinknessmmLabel           matlab.ui.control.Label
        PlateAream2Label                matlab.ui.control.Label
        PlateRatioLWLabel               matlab.ui.control.Label
        PortDiametercmLabel             matlab.ui.control.Label
        NumberofPlateLabel              matlab.ui.control.Label
        PlateGapmmLabel                 matlab.ui.control.Label
        ChevronAngleLabel               matlab.ui.control.Label
        numberOfPlateEx                 matlab.ui.control.NumericEditField
        numberOfPlateMin                matlab.ui.control.NumericEditField
        numberOfPlateMax                matlab.ui.control.NumericEditField
        numberOfPlateStep               matlab.ui.control.NumericEditField
        plateGapEx                      matlab.ui.control.NumericEditField
        plateGapMin                     matlab.ui.control.NumericEditField
        plateGapMax                     matlab.ui.control.NumericEditField
        plateGapStep                    matlab.ui.control.NumericEditField
        plateThicknessMax               matlab.ui.control.NumericEditField
        plateThicknessMin               matlab.ui.control.NumericEditField
        plateThicknessStep              matlab.ui.control.NumericEditField
        plateThicknessEx                matlab.ui.control.NumericEditField
        plateAreaMin                    matlab.ui.control.NumericEditField
        plateAreaMax                    matlab.ui.control.NumericEditField
        plateAreaStep                   matlab.ui.control.NumericEditField
        plateRatioEx                    matlab.ui.control.NumericEditField
        plateAreaEx                     matlab.ui.control.NumericEditField
        portDiameterEx                  matlab.ui.control.NumericEditField
        plateRatioStep                  matlab.ui.control.NumericEditField
        plateRatioMax                   matlab.ui.control.NumericEditField
        plateRatioMin                   matlab.ui.control.NumericEditField
        chevronEx                       matlab.ui.control.NumericEditField
        portDiameterMin                 matlab.ui.control.NumericEditField
        chevronMax                      matlab.ui.control.NumericEditField
        portDiameterStep                matlab.ui.control.NumericEditField
        chevronStep                     matlab.ui.control.NumericEditField
        chevronMin                      matlab.ui.control.NumericEditField
        portDiameterMax                 matlab.ui.control.NumericEditField
        ExactStepLabel                  matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        TestingButton                   matlab.ui.control.Button
        TestingLabel                    matlab.ui.control.Label
        GOButton                        matlab.ui.control.Button
        Switch_2                        matlab.ui.control.Switch
        Switch_3                        matlab.ui.control.Switch
        Switch_4                        matlab.ui.control.Switch
        Switch_5                        matlab.ui.control.Switch
        Switch_6                        matlab.ui.control.Switch
        Switch_7                        matlab.ui.control.Switch
        EnlargementFactorEditFieldLabel  matlab.ui.control.Label
        EnlargementFactorEditField      matlab.ui.control.NumericEditField
        NumberofPassEditFieldLabel      matlab.ui.control.Label
        NumberofPassEditField           matlab.ui.control.NumericEditField
        WorkingLampLabel                matlab.ui.control.Label
        WorkingLamp                     matlab.ui.control.Lamp
        DesignsTab                      matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        AllDesignsTab                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        LoadChosenDesignButton          matlab.ui.control.Button
        resultHEXsTable                 matlab.ui.control.Table
        HeatDutyConvergenceEditFieldLabel  matlab.ui.control.Label
        HeatDutyConvergenceEditField    matlab.ui.control.NumericEditField
        FilterforHeatDutyButton         matlab.ui.control.Button
        ShowAllDesignsButton            matlab.ui.control.Button
        LoadOptimumDesignButton         matlab.ui.control.Button
        SaveShownTableButton            matlab.ui.control.Button
        ValueConvergenceEditFieldLabel  matlab.ui.control.Label
        ValueConvergenceEditField       matlab.ui.control.NumericEditField
        ChooseFilterVariableDropDownLabel  matlab.ui.control.Label
        ChooseFilterVariableDropDown    matlab.ui.control.DropDown
        FilterforButton                 matlab.ui.control.Button
        ValueforEditFieldLabel          matlab.ui.control.Label
        ValueforEditField               matlab.ui.control.NumericEditField
        ChosenDesignTab_2               matlab.ui.container.Tab
        chosenDesignTable               matlab.ui.control.Table
        AnalyzerTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        GraphDimensionSwitchLabel       matlab.ui.control.Label
        GraphDimensionSwitch            matlab.ui.control.ToggleSwitch
        FirstDimensionDropDownLabel     matlab.ui.control.Label
        FirstDimensionDropDown          matlab.ui.control.DropDown
        SecondDimensionDropDownLabel    matlab.ui.control.Label
        SecondDimensionDropDown         matlab.ui.control.DropDown
        ThirdDimensionDropDownLabel     matlab.ui.control.Label
        ThirdDimensionDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        chosenHEXDesign plateHEX % Description
        hexTableMain table % Description
        hexArray % Description
        hexTableShown table % Description
    end
    
    methods (Access = public)
        
        function populateIndividualDesignResults(app)
            
            HEXObject = app.chosenHEXDesign;
            HEXProperties = properties(HEXObject);
            HEXProperties = convertCharsToStrings(HEXProperties);
            Values = cellfun(@(name) HEXObject.(name), HEXProperties);

            propertyTable = table(HEXProperties,Values);
            propertyTable.Properties.RowNames = HEXProperties;
            
            app.chosenDesignTable.Data = propertyTable;
        end
        
    end
    
    methods (Access = private)
        
        function fillResultTable(app)
            app.resultHEXsTable.Data = app.hexTableShown;
            app.resultHEXsTable.ColumnName =app.hexTableShown.Properties.VariableNames;
            app.resultHEXsTable.RowName = app.hexTableShown.Properties.RowNames;
        end
        
        function populateFilterVariableList(app)
            app.ChooseFilterVariableDropDown.Items = app.hexTableShown.Properties.VariableNames; 
            app.ChooseFilterVariableDropDown.ItemsData = app.hexTableShown.Properties.VariableNames;
        end
        
        function AnalyzerStartGraph(app)
            if (app.GraphDimensionSwitch == "2D")
                 
            elseif (app.GraphDimensionSwitch == "3D")
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestingButton
        function TestingButtonPushed(app, event)
            app.TestingLabel.Text = app.Switch.Value;
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            app.WorkingLamp.Color = [0.39,0.83,0.07];
            drawnow();
            %
            if app.Switch.Value == "E"
                NPlate = app.numberOfPlateEx.Value;
            else
                NPoints = (app.numberOfPlateMax.Value - app.numberOfPlateMin.Value) / app.numberOfPlateStep.Value;
                NPoints = app.numberOfPlateStep.Value;
                NPlate = round(linspace(app.numberOfPlateMax.Value, app.numberOfPlateMin.Value, NPoints));
            end
            %
            if app.Switch_2.Value == "E"
                plateGap = app.plateGapEx.Value;
            else
                NPoints = (app.plateGapMax.Value - app.plateGapMin.Value) / app.plateGapStep.Value;
                NPoints = app.plateGapStep.Value;
                plateGap = linspace(app.plateGapMax.Value,  app.plateGapMin.Value, NPoints);
            end
            %
            if app.Switch_3.Value == "E"
                plateThickness = app.plateThicknessEx.Value;
            else
                NPoints = (app.plateThicknessMax.Value - app.plateThicknessMin.Value) / app.plateThicknessStep.Value;
                NPoints = app.plateThicknessStep.Value;
                plateThickness = linspace(app.plateThicknessMax.Value,  app.plateThicknessMin.Value, NPoints);
            end
            %
            if app.Switch_4.Value == "E"
                plateArea = app.plateAreaEx.Value;
            else
                NPoints = (app.plateAreaMax.Value - app.plateAreaMin.Value) / app.plateAreaStep.Value;
                NPoints = app.plateAreaStep.Value;
                plateArea = linspace(app.plateAreaMax.Value,  app.plateAreaMin.Value, NPoints);
            end
            %
            if app.Switch_5.Value == "E"
                plateRatio = app.plateRatioEx.Value;
            else
                NPoints = (app.plateRatioMax.Value - app.plateRatioMin.Value) / app.plateRatioStep.Value;
                NPoints = app.plateRatioStep.Value;
                plateRatio = linspace(app.plateRatioMax.Value,  app.plateRatioMin.Value, NPoints);
            end
            %
            if app.Switch_6.Value == "E"
                diameterPort  = app.portDiameterEx.Value;
            else
                NPoints = (app.portDiameterMax.Value - app.portDiameterMin.Value) / app.portDiameterStep.Value;
                NPoints = app.portDiameterStep.Value;
                diameterPort = linspace(app.portDiameterMax.Value,  app.portDiameterMin.Value, NPoints);
            end
            %
            if app.Switch_7.Value == "E"
                chevronAngle = app.chevronEx.Value;
            else
                NPoints = (app.chevronMax.Value - app.chevronMin.Value) / app.chevronStep.Value;
                NPoints = app.chevronStep.Value;
                chevronAngle = linspace(app.chevronMax.Value,  app.chevronMin.Value, NPoints);
            end
            %
            flowRateCold = app.FlowRateColdkgsEditField.Value;
            flowRateHot = app.FlowRateHotkgsEditField.Value;
            TColdIn = app.InletTemperatureColdKEditField.Value;
            TColdOut = app.OutletTemperatureColdKEditField.Value;
            THotIn = app.InletTemperatureHotKEditField.Value;
            THotOut = app.OutletTemperatureHotKEditField.Value;
            
            enlargementFactor = app.EnlargementFactorEditField.Value;
            NPass = app.NumberofPassEditField.Value;
            
            foilingFactorCold = app.FoulingResistanceColdm2KWEditField.Value;
            foilingFactorHot = app.FoulingResistanceHotm2KWEditField.Value;
            
            lifeTime = app.LifeTimeyearEditField.Value;
            efficiencyCold = app.PumpEfficiencyColdEditField.Value;
            efficiencyHot = app.PumpEfficiencyHotEditField.Value;
            
            
            parameterArrays = {flowRateCold,flowRateHot,...
                TColdIn,TColdOut,THotIn,THotOut,...
                NPlate,plateGap,plateThickness,plateArea,plateRatio,...
                diameterPort,enlargementFactor, NPass, chevronAngle, foilingFactorCold, foilingFactorHot,...
                lifeTime,efficiencyCold,efficiencyHot};
            tic

            app.hexArray = combinationalArrayLooper(parameterArrays, @plateHEX);
            app.hexTableMain = KF.objectArray2Table(app.hexArray);
            aaa = app.hexArray;
            
            app.TestingLabel.Text = string(NPlate);
            app.WorkingLamp.Color = [1.00,1.00,1.00];
            errordlg(append("Total of ",string(length(app.hexArray))," heat exchangers designed in ",string(toc)," seconds!"),"Design Finish")
        end

        % Button pushed function: LoadChosenDesignButton
        function LoadChosenDesignButtonPushed(app, event)
            
            app.populateIndividualDesignResults();
        end

        % Button pushed function: ShowAllDesignsButton
        function ShowAllDesignsButtonPushed(app, event)
            app.hexTableShown = app.hexTableMain;
            app.fillResultTable();
            app.populateFilterVariableList();
        end

        % Button pushed function: SaveShownTableButton
        function SaveShownTableButtonPushed(app, event)
            filename = append("All_Hex_Designs-",append(KF.getDateTimeStrWOSpace,".xls"));
            writetable(app.hexTableShown, ...
                filename);
        end

        % Value changed function: ChooseFilterVariableDropDown
        function ChooseFilterVariableDropDownValueChanged(app, event)
            variable = app.ChooseFilterVariableDropDown.Value;
            app.FilterforButton.Text = append("Filter for ",variable);
            app.ValueConvergenceEditFieldLabel.Text = append(variable," Convergence");
            app.ValueforEditFieldLabel.Text = append("Value of ",variable)

        end

        % Button pushed function: FilterforHeatDutyButton
        function FilterforHeatDutyButtonPushed(app, event)
            wantedHeatDutyConvergence = app.HeatDutyConvergenceEditField.Value;
            app.hexTableShown = KF.filterTable(app.hexTableShown, "heatDutyCalculatedWithFoiling" ...
                ,app.hexTableMain.heatDuty(1),wantedHeatDutyConvergence);
            app.fillResultTable();
        end

        % Button pushed function: FilterforButton
        function FilterforButtonPushed(app, event)
            wantedVariableConvergence = app.ValueConvergenceEditField.Value;
            variable = app.ChooseFilterVariableDropDown.Value;
            app.hexTableShown = KF.filterTable(app.hexTableShown, variable ...
                ,app.ValueforEditField.Value,wantedVariableConvergence);
            app.fillResultTable();
        end

        % Selection change function: TabGroup2
        function TabGroup2SelectionChanged(app, event)

            if "Analyzer" == app.TabGroup2.SelectedTab.Title;


                items = app.hexTableMain.Properties.Variables;
                
                app.FirstDimensionDropDown.Items = items;
                app.FirstDimensionDropDown.ItemsData = items;
                
                app.SecondDimensionDropDown.Items =  items;
                app.SecondDimensionDropDown.ItemsData = items;
                
                app.ThirdDimensionDropDown.Items = items;
                app.ThirdDimensionDropDown.ItemsData = items;
                
                app.AnalyzerStartGraph();
            end
        end

        % Value changed function: GraphDimensionSwitch
        function GraphDimensionSwitchValueChanged(app, event)
            if  "2D" == app.GraphDimensionSwitch.Value;
                app.FirstDimensionDropDown.Visible = false;
            else
                app.FirstDimensionDropDown.Visible = true;
            end
            app.AnalyzerStartGraph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DesignParametersTab = uitab(app.TabGroup);
            app.DesignParametersTab.Title = 'Design Parameters';
            app.TabGroup3 = uitabgroup(app.DesignParametersTab);
            app.ProjectParametersTab = uitab(app.TabGroup3);
            app.ProjectParametersTab.Title = 'Project Parameters';
            app.GridLayout_2 = uigridlayout(app.ProjectParametersTab);
            app.ColdLabel = uilabel(app.GridLayout_2);
            app.ColdLabel.Text = 'Cold';
            app.HotLabel = uilabel(app.GridLayout_2);
            app.HotLabel.Text = 'Hot';
            app.FlowRateHotkgsEditFieldLabel = uilabel(app.GridLayout_2);
            app.FlowRateHotkgsEditFieldLabel.Text = {'Flow Rate Hot'; '(kg/s)'};
            app.FlowRateHotkgsEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.FlowRateHotkgsEditField.Value = 10.43;
            app.InletTemperatureHotKEditFieldLabel = uilabel(app.GridLayout_2);
            app.InletTemperatureHotKEditFieldLabel.Text = {'Inlet Temperature Hot'; '(K)'};
            app.InletTemperatureHotKEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.InletTemperatureHotKEditField.Value = 419.2;
            app.OutletTemperatureHotKEditFieldLabel = uilabel(app.GridLayout_2);
            app.OutletTemperatureHotKEditFieldLabel.Text = {'Outlet Temperature Hot'; '(K)'};
            app.OutletTemperatureHotKEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.OutletTemperatureHotKEditField.Value = 383;
            app.PumpEfficiencyHotEditFieldLabel = uilabel(app.GridLayout_2);
            app.PumpEfficiencyHotEditFieldLabel.Text = {'Pump Efficiency Hot'; '(%)'};
            app.PumpEfficiencyHotEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.PumpEfficiencyHotEditField.Value = 68.4;
            app.FlowRateColdkgsEditFieldLabel = uilabel(app.GridLayout_2);
            app.FlowRateColdkgsEditFieldLabel.Text = {'Flow Rate Cold'; '(kg/s)'};
            app.FlowRateColdkgsEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.FlowRateColdkgsEditField.Value = 11.95;
            app.InletTemperatureColdKEditFieldLabel = uilabel(app.GridLayout_2);
            app.InletTemperatureColdKEditFieldLabel.Text = {'Inlet Temperature Cold'; '(K)'};
            app.InletTemperatureColdKEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.InletTemperatureColdKEditField.Value = 371.8;
            app.OutletTemperatureColdKEditFieldLabel = uilabel(app.GridLayout_2);
            app.OutletTemperatureColdKEditFieldLabel.Text = {'Outlet Temperature Cold'; '(K)'};
            app.OutletTemperatureColdKEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.OutletTemperatureColdKEditField.Value = 405;
            app.PumpEfficiencyColdEditFieldLabel = uilabel(app.GridLayout_2);
            app.PumpEfficiencyColdEditFieldLabel.Text = {'Pump Efficiency Cold'; '(%)'};
            app.PumpEfficiencyColdEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.PumpEfficiencyColdEditField.Value = 77.5;
            app.LifeTimeyearEditFieldLabel = uilabel(app.GridLayout_2);
            app.LifeTimeyearEditFieldLabel.Text = {'Life Time'; '(year)'};
            app.LifeTimeyearEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.LifeTimeyearEditField.Value = 20;
            app.PlateMaterialLabel = uilabel(app.GridLayout_2);
            app.PlateMaterialLabel.Text = 'Plate Material';
            app.GasketMaterialLabel = uilabel(app.GridLayout_2);
            app.GasketMaterialLabel.Text = 'Gasket Material';
            app.StainlessSteelAISI316Label = uilabel(app.GridLayout_2);
            app.StainlessSteelAISI316Label.Text = 'Stainless Steel (AISI-316)';
            app.PlateMaterialLabel_3 = uilabel(app.GridLayout_2);
            app.PlateMaterialLabel_3.Text = 'Plate Material';
            app.FoulingResistanceColdm2KWEditFieldLabel = uilabel(app.GridLayout_2);
            app.FoulingResistanceColdm2KWEditFieldLabel.Text = {'Fouling Resistance Cold'; '(m^2* K / W)'};
            app.FoulingResistanceColdm2KWEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.FoulingResistanceColdm2KWEditField.ValueDisplayFormat = '%.6f';
            app.FoulingResistanceColdm2KWEditField.Value = 4.3e-05;
            app.FoulingResistanceHotm2KWEditFieldLabel = uilabel(app.GridLayout_2);
            app.FoulingResistanceHotm2KWEditFieldLabel.Text = {'Fouling Resistance Hot'; '(m^2* K / W)'};
            app.FoulingResistanceHotm2KWEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.FoulingResistanceHotm2KWEditField.ValueDisplayFormat = '%.6f';
            app.FoulingResistanceHotm2KWEditField.Value = 4.3e-05;
            app.HEXPropertiesTab = uitab(app.TabGroup3);
            app.HEXPropertiesTab.Title = 'HEX Properties';
            app.GridLayout = uigridlayout(app.HEXPropertiesTab);
            app.MinimumValueLabel = uilabel(app.GridLayout);
            app.MinimumValueLabel.Text = 'Minimum Value';
            app.MaximumValueLabel = uilabel(app.GridLayout);
            app.MaximumValueLabel.Text = 'Maximum Value';
            app.IterationSizeLabel = uilabel(app.GridLayout);
            app.IterationSizeLabel.Text = 'Iteration Size';
            app.ExactLabel = uilabel(app.GridLayout);
            app.ExactLabel.Text = 'Exact';
            app.ParameterLabel = uilabel(app.GridLayout);
            app.ParameterLabel.Text = 'Parameter';
            app.PlateThinknessmmLabel = uilabel(app.GridLayout);
            app.PlateThinknessmmLabel.Text = {'Plate Thinkness'; '(mm)'};
            app.PlateAream2Label = uilabel(app.GridLayout);
            app.PlateAream2Label.Text = {'Plate Area'; '(m^2)'};
            app.PlateRatioLWLabel = uilabel(app.GridLayout);
            app.PlateRatioLWLabel.Text = {'Plate Ratio'; '(L/W)'};
            app.PortDiametercmLabel = uilabel(app.GridLayout);
            app.PortDiametercmLabel.Text = {'Port Diameter'; '(cm)'};
            app.NumberofPlateLabel = uilabel(app.GridLayout);
            app.NumberofPlateLabel.Text = 'Number of Plate';
            app.PlateGapmmLabel = uilabel(app.GridLayout);
            app.PlateGapmmLabel.Text = {'Plate Gap '; '(mm)'};
            app.ChevronAngleLabel = uilabel(app.GridLayout);
            app.ChevronAngleLabel.Text = 'Chevron Angle';
            app.numberOfPlateEx = uieditfield(app.GridLayout, 'numeric');
            app.numberOfPlateEx.Value = 201;
            app.numberOfPlateMin = uieditfield(app.GridLayout, 'numeric');
            app.numberOfPlateMin.Value = 3;
            app.numberOfPlateMax = uieditfield(app.GridLayout, 'numeric');
            app.numberOfPlateMax.Value = 50;
            app.numberOfPlateStep = uieditfield(app.GridLayout, 'numeric');
            app.numberOfPlateStep.Value = 48;
            app.plateGapEx = uieditfield(app.GridLayout, 'numeric');
            app.plateGapEx.Value = 1.9;
            app.plateGapMin = uieditfield(app.GridLayout, 'numeric');
            app.plateGapMin.Value = 1.5;
            app.plateGapMax = uieditfield(app.GridLayout, 'numeric');
            app.plateGapMax.Value = 4.5;
            app.plateGapStep = uieditfield(app.GridLayout, 'numeric');
            app.plateGapStep.Value = 3;
            app.plateThicknessMax = uieditfield(app.GridLayout, 'numeric');
            app.plateThicknessMax.Value = 1.2;
            app.plateThicknessMin = uieditfield(app.GridLayout, 'numeric');
            app.plateThicknessMin.Value = 0.5;
            app.plateThicknessStep = uieditfield(app.GridLayout, 'numeric');
            app.plateThicknessStep.Value = 5;
            app.plateThicknessEx = uieditfield(app.GridLayout, 'numeric');
            app.plateThicknessEx.Value = 0.5;
            app.plateAreaMin = uieditfield(app.GridLayout, 'numeric');
            app.plateAreaMin.Value = 0.2;
            app.plateAreaMax = uieditfield(app.GridLayout, 'numeric');
            app.plateAreaMax.Value = 2.2;
            app.plateAreaStep = uieditfield(app.GridLayout, 'numeric');
            app.plateAreaStep.Value = 21;
            app.plateRatioEx = uieditfield(app.GridLayout, 'numeric');
            app.plateRatioEx.Value = 3.5;
            app.plateAreaEx = uieditfield(app.GridLayout, 'numeric');
            app.plateAreaEx.Value = 1;
            app.portDiameterEx = uieditfield(app.GridLayout, 'numeric');
            app.portDiameterEx.Value = 20;
            app.plateRatioStep = uieditfield(app.GridLayout, 'numeric');
            app.plateRatioStep.Value = 7;
            app.plateRatioMax = uieditfield(app.GridLayout, 'numeric');
            app.plateRatioMax.Value = 7.8;
            app.plateRatioMin = uieditfield(app.GridLayout, 'numeric');
            app.plateRatioMin.Value = 1.8;
            app.chevronEx = uieditfield(app.GridLayout, 'numeric');
            app.chevronEx.Value = 30;
            app.portDiameterMin = uieditfield(app.GridLayout, 'numeric');
            app.portDiameterMin.Value = 10;
            app.chevronMax = uieditfield(app.GridLayout, 'numeric');
            app.chevronMax.Value = 60;
            app.portDiameterStep = uieditfield(app.GridLayout, 'numeric');
            app.portDiameterStep.Value = 7;
            app.chevronStep = uieditfield(app.GridLayout, 'numeric');
            app.chevronStep.Value = 7;
            app.chevronMin = uieditfield(app.GridLayout, 'numeric');
            app.chevronMin.Value = 30;
            app.portDiameterMax = uieditfield(app.GridLayout, 'numeric');
            app.portDiameterMax.Value = 50;
            app.ExactStepLabel = uilabel(app.GridLayout);
            app.ExactStepLabel.Text = 'Exact / Step';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'E', 'S'};
            app.Switch.ItemsData = {'E', 'S'};
            app.Switch.Value = 'S';
            app.TestingButton = uibutton(app.GridLayout, 'push');
            app.TestingButton.ButtonPushedFcn = createCallbackFcn(app, @TestingButtonPushed, true);
            app.TestingButton.Text = 'Testing';
            app.TestingLabel = uilabel(app.GridLayout);
            app.GOButton = uibutton(app.GridLayout, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.Switch_2 = uiswitch(app.GridLayout, 'slider');
            app.Switch_2.Items = {'E', 'S'};
            app.Switch_2.ItemsData = {'E', 'S'};
            app.Switch_2.Value = 'S';
            app.Switch_3 = uiswitch(app.GridLayout, 'slider');
            app.Switch_3.Items = {'E', 'S'};
            app.Switch_3.ItemsData = {'E', 'S'};
            app.Switch_3.Value = 'S';
            app.Switch_4 = uiswitch(app.GridLayout, 'slider');
            app.Switch_4.Items = {'E', 'S'};
            app.Switch_4.ItemsData = {'E', 'S'};
            app.Switch_4.Value = 'S';
            app.Switch_5 = uiswitch(app.GridLayout, 'slider');
            app.Switch_5.Items = {'E', 'S'};
            app.Switch_5.ItemsData = {'E', 'S'};
            app.Switch_5.Value = 'S';
            app.Switch_6 = uiswitch(app.GridLayout, 'slider');
            app.Switch_6.Items = {'E', 'S'};
            app.Switch_6.ItemsData = {'E', 'S'};
            app.Switch_6.Value = 'E';
            app.Switch_7 = uiswitch(app.GridLayout, 'slider');
            app.Switch_7.Items = {'E', 'S'};
            app.Switch_7.ItemsData = {'E', 'S'};
            app.Switch_7.Value = 'S';
            app.EnlargementFactorEditFieldLabel = uilabel(app.GridLayout);
            app.EnlargementFactorEditFieldLabel.Text = {'Enlargement'; 'Factor'};
            app.EnlargementFactorEditField = uieditfield(app.GridLayout, 'numeric');
            app.EnlargementFactorEditField.Value = 1.17;
            app.NumberofPassEditFieldLabel = uilabel(app.GridLayout);
            app.NumberofPassEditFieldLabel.Text = 'Number of Pass';
            app.NumberofPassEditField = uieditfield(app.GridLayout, 'numeric');
            app.NumberofPassEditField.Value = 1;
            app.WorkingLampLabel = uilabel(app.GridLayout);
            app.WorkingLampLabel.Text = 'Working';
            app.WorkingLamp = uilamp(app.GridLayout);
            app.DesignsTab = uitab(app.TabGroup);
            app.DesignsTab.Title = 'Designs';
            app.TabGroup2 = uitabgroup(app.DesignsTab);
            app.TabGroup2.SelectionChangedFcn = createCallbackFcn(app, @TabGroup2SelectionChanged, true);
            app.AllDesignsTab = uitab(app.TabGroup2);
            app.AllDesignsTab.Title = 'All Designs';
            app.GridLayout2 = uigridlayout(app.AllDesignsTab);
            app.LoadChosenDesignButton = uibutton(app.GridLayout2, 'push');
            app.LoadChosenDesignButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChosenDesignButtonPushed, true);
            app.LoadChosenDesignButton.Text = 'Load Chosen Design';
            app.resultHEXsTable = uitable(app.GridLayout2);
            app.resultHEXsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.resultHEXsTable.RowName = {};
            app.resultHEXsTable.ColumnSortable = true;
            app.HeatDutyConvergenceEditFieldLabel = uilabel(app.GridLayout2);
            app.HeatDutyConvergenceEditFieldLabel.Text = {'Heat Duty Convergence'; '(%)'};
            app.HeatDutyConvergenceEditField = uieditfield(app.GridLayout2, 'numeric');
            app.FilterforHeatDutyButton = uibutton(app.GridLayout2, 'push');
            app.FilterforHeatDutyButton.ButtonPushedFcn = createCallbackFcn(app, @FilterforHeatDutyButtonPushed, true);
            app.FilterforHeatDutyButton.Text = 'Filter for Heat Duty';
            app.ShowAllDesignsButton = uibutton(app.GridLayout2, 'push');
            app.ShowAllDesignsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAllDesignsButtonPushed, true);
            app.ShowAllDesignsButton.Text = 'Show All Designs';
            app.LoadOptimumDesignButton = uibutton(app.GridLayout2, 'push');
            app.LoadOptimumDesignButton.Text = 'Load Optimum Design';
            app.SaveShownTableButton = uibutton(app.GridLayout2, 'push');
            app.SaveShownTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveShownTableButtonPushed, true);
            app.SaveShownTableButton.Text = 'Save Shown Table';
            app.ValueConvergenceEditFieldLabel = uilabel(app.GridLayout2);
            app.ValueConvergenceEditFieldLabel.Text = {'Value Convergence'; '(%)'};
            app.ValueConvergenceEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ChooseFilterVariableDropDownLabel = uilabel(app.GridLayout2);
            app.ChooseFilterVariableDropDownLabel.Text = 'Choose Filter Variable';
            app.ChooseFilterVariableDropDown = uidropdown(app.GridLayout2);
            app.ChooseFilterVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseFilterVariableDropDownValueChanged, true);
            app.FilterforButton = uibutton(app.GridLayout2, 'push');
            app.FilterforButton.ButtonPushedFcn = createCallbackFcn(app, @FilterforButtonPushed, true);
            app.FilterforButton.Text = 'Filter for ';
            app.ValueforEditFieldLabel = uilabel(app.GridLayout2);
            app.ValueforEditFieldLabel.Text = 'Value for';
            app.ValueforEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ChosenDesignTab_2 = uitab(app.TabGroup2);
            app.ChosenDesignTab_2.Title = 'ChosenDesign';
            app.chosenDesignTable = uitable(app.ChosenDesignTab_2);
            app.chosenDesignTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.chosenDesignTable.RowName = {};
            app.AnalyzerTab = uitab(app.TabGroup2);
            app.AnalyzerTab.Title = 'Analyzer';
            app.GridLayout3 = uigridlayout(app.AnalyzerTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GraphDimensionSwitchLabel = uilabel(app.GridLayout3);
            app.GraphDimensionSwitchLabel.Text = 'Graph Dimension';
            app.GraphDimensionSwitch = uiswitch(app.GridLayout3, 'toggle');
            app.GraphDimensionSwitch.Items = {'3D', '2D'};
            app.GraphDimensionSwitch.ValueChangedFcn = createCallbackFcn(app, @GraphDimensionSwitchValueChanged, true);
            app.GraphDimensionSwitch.Value = '3D';
            app.FirstDimensionDropDownLabel = uilabel(app.GridLayout3);
            app.FirstDimensionDropDownLabel.Text = 'First Dimension';
            app.FirstDimensionDropDown = uidropdown(app.GridLayout3);
            app.SecondDimensionDropDownLabel = uilabel(app.GridLayout3);
            app.SecondDimensionDropDownLabel.Text = 'Second Dimension';
            app.SecondDimensionDropDown = uidropdown(app.GridLayout3);
            app.ThirdDimensionDropDownLabel = uilabel(app.GridLayout3);
            app.ThirdDimensionDropDownLabel.Text = 'Third Dimension';
            app.ThirdDimensionDropDown = uidropdown(app.GridLayout3);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HEXDesignerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end